classdef FileSelectorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FileSelector  matlab.ui.Figure
        ChooseoneormultiplefilestoprocessListBoxLabel  matlab.ui.control.Label
        FileList      matlab.ui.control.ListBox
        FinishButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % The main app which called this helper.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, file_list)
            if nargin == 1
                main = 0;
                file_list = {'no', 'main', 'app', 'used'};
            end
            app.MainApp = main;
            file_names = cell(1, length(file_list));
            for i=1:length(file_list)
                [~, name, ~] = fileparts(file_list{i});
                file_names{i} = name;
            end
            set(app.FileList, 'Items', file_names)
        end

        % Button pushed function: FinishButton
        function select(app, event)
            subset_array = find(ismember(app.FileList.Items, app.FileList.Value));
            updateFileSubset(app.MainApp, subset_array);
            app.MainApp.FileSelectorButton.Enable = 'on';
            delete(app);
        end

        % Close request function: FileSelector
        function FileSelectorCloseRequest(app, event)
            app.MainApp.FileSelectorButton.Enable = 'on';
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FileSelector = uifigure('Visible', 'off');
            app.FileSelector.Name = 'UI Figure';
            app.FileSelector.CloseRequestFcn = createCallbackFcn(app, @FileSelectorCloseRequest, true);
            app.ChooseoneormultiplefilestoprocessListBoxLabel = uilabel(app.FileSelector);
            app.ChooseoneormultiplefilestoprocessListBoxLabel.Text = 'Choose one or multiple files to process';
            app.FileList = uilistbox(app.FileSelector);
            app.FileList.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.FileList.Multiselect = 'on';
            app.FileList.Value = {'Item 1'};
            app.FinishButton = uibutton(app.FileSelector, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @select, true);
            app.FinishButton.Text = 'Press when finished';
            % Show the figure after all components are created
            app.FileSelector.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FileSelectorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FileSelector)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FileSelector)
        end
    end
end