classdef MotionProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionUI                        matlab.ui.Figure
        OuterGrid                       matlab.ui.container.GridLayout
        MarkersDataButton               matlab.ui.control.Button
        GRFDataButton                   matlab.ui.control.Button
        EMGDataButton                   matlab.ui.control.Button
        CalorimetryDataButton           matlab.ui.control.Button
        MarkersDataPath                 matlab.ui.control.EditField
        GRFDataPath                     matlab.ui.control.EditField
        EMGDataPath                     matlab.ui.control.EditField
        CalorimetryDataPath             matlab.ui.control.EditField
        SubsetButton                    matlab.ui.control.Button
        SubsetSelectedLabel             matlab.ui.control.Label
        SubsetIndicator                 matlab.ui.control.Lamp
        FooterToolbox                   matlab.ui.control.Label
        FooterAuthor                    matlab.ui.control.Label
        FeedbackPanel                   matlab.ui.control.TextArea
        SaveDirectoryButton             matlab.ui.control.Button
        SaveDirectoryPath               matlab.ui.control.EditField
        MarkersDataClearButton          matlab.ui.control.Button
        GRFDataClearButton              matlab.ui.control.Button
        EMGDataClearButton              matlab.ui.control.Button
        CalorimetryDataClearButton      matlab.ui.control.Button
        SaveDirectoryClearButton        matlab.ui.control.Button
        ProcessingTabs                  matlab.ui.container.TabGroup
        HighLevelTab                    matlab.ui.container.Tab
        HighLevelGrid                   matlab.ui.container.GridLayout
        MotionCaptureConfigurationPanel  matlab.ui.container.Panel
        MotionCaptureConfigurationGrid  matlab.ui.container.GridLayout
        TranslationLabel                matlab.ui.control.Label
        XEditFieldLabel_2               matlab.ui.control.Label
        XRotation                       matlab.ui.control.NumericEditField
        YEditFieldLabel_2               matlab.ui.control.Label
        YRotation                       matlab.ui.control.NumericEditField
        ZEditFieldLabel_2               matlab.ui.control.Label
        ZRotation                       matlab.ui.control.NumericEditField
        RotationdegLabel                matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        XOffset                         matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YOffset                         matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZOffset                         matlab.ui.control.NumericEditField
        ForcePlateConfigurationPanel    matlab.ui.container.Panel
        ForcePlateConfigurationGrid     matlab.ui.container.GridLayout
        ForwardLabel                    matlab.ui.control.Label
        UpLabel                         matlab.ui.control.Label
        RightLabel                      matlab.ui.control.Label
        GRFForwardDropDown              matlab.ui.control.DropDown
        GRFUpDropDown                   matlab.ui.control.DropDown
        GRFRightDropDown                matlab.ui.control.DropDown
        TiltEditField                   matlab.ui.control.NumericEditField
        TiltdegLabel                    matlab.ui.control.Label
        MotionSynchronisationPanel      matlab.ui.container.Panel
        MotionSynchronisationGrid       matlab.ui.container.GridLayout
        BaselineLabel                   matlab.ui.control.Label
        TimeDelaysLabel                 matlab.ui.control.Label
        MarkersCheckBox                 matlab.ui.control.CheckBox
        GRFCheckBox                     matlab.ui.control.CheckBox
        EMGCheckBox                     matlab.ui.control.CheckBox
        CalorimetryCheckBox             matlab.ui.control.CheckBox
        MarkersDelay                    matlab.ui.control.NumericEditField
        GRFDelay                        matlab.ui.control.NumericEditField
        EMGDelay                        matlab.ui.control.NumericEditField
        CalorimetryDelay                matlab.ui.control.NumericEditField
        GaitSegmentationPanel           matlab.ui.container.Panel
        GaitSegmentationGrid            matlab.ui.container.GridLayout
        ModePanel                       matlab.ui.container.ButtonGroup
        StanceButton                    matlab.ui.control.RadioButton
        KinematicsButton                matlab.ui.control.RadioButton
        FeetPanel                       matlab.ui.container.ButtonGroup
        LeftButton                      matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        BothButton                      matlab.ui.control.RadioButton
        SpeedCompensationPanel          matlab.ui.container.Panel
        SpeedCompensationGrid           matlab.ui.container.GridLayout
        SpeedDataPath                   matlab.ui.control.EditField
        SpeedModeButton                 matlab.ui.control.Button
        SpeedmsEditFieldLabel           matlab.ui.control.Label
        SpeedInput                      matlab.ui.control.NumericEditField
        DataProcessingOptionsPanel      matlab.ui.container.Panel
        DataProcessingOptionsGrid       matlab.ui.container.GridLayout
        MotionSynchronisationLabel      matlab.ui.control.Label
        MotionSynchronisationSwitch     matlab.ui.control.Switch
        SpeedCompensationSwitchLabel    matlab.ui.control.Label
        SpeedCompensationSwitch         matlab.ui.control.Switch
        GaitSegmentationLabel           matlab.ui.control.Label
        GaitSegmentationSwitch          matlab.ui.control.Switch
        ApplicationPanel                matlab.ui.container.Panel
        DebuggingGrid                   matlab.ui.container.GridLayout
        ErrorSwitch                     matlab.ui.control.Switch
        ErrorInfoLabel                  matlab.ui.control.Label
        SaveProcessingSettingsButton    matlab.ui.control.Button
        LowLevelTab                     matlab.ui.container.Tab
        LowLevelGrid                    matlab.ui.container.GridLayout
        SpeedCompensationPanel_2        matlab.ui.container.Panel
        LowLevelSpeedCompensationGrid   matlab.ui.container.GridLayout
        SpeedLabelEditFieldLabel        matlab.ui.control.Label
        SpeedLabelEditField             matlab.ui.control.EditField
        FilterEditFieldLabel            matlab.ui.control.Label
        SpeedFilterEditField            matlab.ui.control.NumericEditField
        SpeedThresholdCheckBox          matlab.ui.control.CheckBox
        SpeedThresholdLabel             matlab.ui.control.Label
        EMGProcessingPanel              matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        BPLowerCutoffEditFieldLabel     matlab.ui.control.Label
        BPLowerCutoffEditField          matlab.ui.control.NumericEditField
        BPUpperCutoffEditFieldLabel     matlab.ui.control.Label
        BPUpperCutoffEditField          matlab.ui.control.NumericEditField
        LPCutoffEditFieldLabel          matlab.ui.control.Label
        LPCutoffEditField               matlab.ui.control.NumericEditField
        GRFProcessingPanel              matlab.ui.container.Panel
        FileParsingPanel                matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        LoadGRFMapButton                matlab.ui.control.Button
        LoadEMGMapButton                matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        ProcessButton                   matlab.ui.control.Button
    end

    properties (Access = private)
       
        FileSelector % Separate helper app for selecting subset of files
        FileSet
        FileSubset
        NInputs = 0
        BaselineSelected = false
        
    end
    
    methods (Access = private)
        
        function value = getFirstDataPath(app)
            
            options = {'MarkersDataPath', 'GRFDataPath', 'EMGDataPath', ...
                'CalorimetryDataPath'};
            
            value = '';
            for i = 1:length(options)
                if ~isempty(app.(options{i}).Value)
                    value = app.(options{i}).Value;
                    break;
                end
            end
            
        end
        
        function updateFeedbackPanel(app, str, err)
            
            app.FeedbackPanel.Value = str;
            if err
                beep;
            end
            
        end
        
        function adjustMocapConfigurationOptions(app, value)
            app.XRotation.Enable = value;
            app.YRotation.Enable = value;
            app.ZRotation.Enable = value;
            app.XOffset.Enable = value;
            app.YOffset.Enable = value;
            app.ZOffset.Enable = value;
        end
        
        function adjustGRFConfigurationOptions(app, value)
            app.TiltEditField.Enable = value;
            app.GRFForwardDropDown.Enable = value;
            app.GRFUpDropDown.Enable = value;
            app.GRFRightDropDown.Enable = value;
        end
        
        function adjustSpeedCompensationOptions(app, value)
            
            app.SpeedModeButton.Enable = value;
            
            if strcmp(value, 'Off')
                app.SpeedDataPath.Enable = value;
                app.SpeedInput.Enable = value;
            else
                switch app.SpeedModeButton.Text
                    case 'Unload'
                        app.SpeedDataPath.Enable = 'On';
                    case 'Load'
                        app.SpeedInput.Enable = 'On';
                end
            end
            
        end
        
        function adjustMotionSynchronisationOptions(app, value, array)
            
            
            if array(1)
                app.MarkersCheckBox.Enable = value;
                app.MarkersDelay.Enable = value;
            end
            if array(2)
                app.GRFCheckBox.Enable = value;
                app.GRFDelay.Enable = value;
            end
            if array(3)
                app.EMGCheckBox.Enable = value;
                app.EMGDelay.Enable = value;
            end
            if array(4)
                app.CalorimetryCheckBox.Enable = value;
                app.CalorimetryDelay.Enable = value;
            end
            
            app.adjustBaselineChecks([0, 0, 0, 0])
            
        end
        
        function adjustGaitSegmentationOptions(app, value)
            
            if strcmp(value, 'On')
                if ~isempty(app.MarkersDataPath.Value)
                    app.KinematicsButton.Enable = value;
                else
                    app.StanceButton.Value = 1;
                end
                if ~isempty(app.GRFDataPath.Value)
                    app.StanceButton.Enable = value;
                else
                    app.KinematicsButton.Value = 1;
                end
            else
                app.KinematicsButton.Enable = value;
                app.StanceButton.Enable = value;
            end
            app.LeftButton.Enable = value;
            app.RightButton.Enable = value;
            app.BothButton.Enable = value;
            
        end
        
        function states = getDataProcessingOptionStates(app)
            states{1} = app.MotionSynchronisationSwitch.Value;
            states{2} = app.SpeedCompensationSwitch.Value;
            states{3} = app.GaitSegmentationSwitch.Value;
            
        end
        
        function changeDataProcessingOptions(app, states, value)
            
            % Simulate turning switches off and on again
            if strcmp(states{1}, 'On')
                app.MotionSynchronisationSwitch.Value = value;
                app.motionSynchronisationSwitchPressed();
            end
            if strcmp(states{2}, 'On')
                app.SpeedCompensationSwitch.Value = value;
                app.speedCompensationSwitchPressed();
            end
            if strcmp(states{3}, 'On')
                app.GaitSegmentationSwitch.Value = value;
                app.gaitSegmentationSwitchPressed();
            end
            
        end
        
        function resetDataProcessingOptions(app)
            
            states = app.getDataProcessingOptionStates();
            app.changeDataProcessingOptions(states, 'Off');
            app.changeDataProcessingOptions(states, 'On');
            
        end
        
        function adjustBaselineChecks(app, values)
            
            app.MarkersCheckBox.Value = values(1);
            app.GRFCheckBox.Value = values(2);
            app.EMGCheckBox.Value = values(3);
            app.CalorimetryCheckBox.Value = values(4);
            
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MarkersDataButton
        function getMarkersDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set marker data path
                app.MarkersDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing marker data.');
                
                % Refocus 
                figure(app.MotionUI);
                
                % Enable clear option
                app.MarkersDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
                
                % Enable motion capture configuration options
                app.adjustMocapConfigurationOptions('On');
            catch
            end
        end

        % Button pushed function: MarkersDataClearButton
        function clearMarkersDataPath(app, event)
            % Clear marker data path
            app.MarkersDataPath.Value = '';
            app.MarkersDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
            % Disable marker orientation options
            app.adjustMocapConfigurationOptions('Off');
        end

        % Button pushed function: GRFDataButton
        function getGRFDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set data path
                app.GRFDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing GRF data.');
                
                % Refocus
                figure(app.MotionUI);
                
                % Enable clear option
                app.GRFDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
                
                % Enable the GRF orientation options
                app.adjustGRFConfigurationOptions('On');
            catch
            end
        end

        % Button pushed function: EMGDataButton
        function getEMGDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set data path
                app.EMGDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing EMG data.');
                
                % Refocus
                figure(app.MotionUI);
                
                % Enable clear option
                app.EMGDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
            catch
            end
        end

        % Button pushed function: CalorimetryDataButton
        function getCalorimetryDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set data path
                app.CalorimetryDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing calorimetry data.');
                
                % Refocus
                figure(app.MotionUI);
                
                % Enable clear option
                app.CalorimetryDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
            catch
            end
        end

        % Button pushed function: SaveDirectoryButton
        function getSaveDirectoryPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set save directory
                app.SaveDirectoryPath.Value = ...
                    uigetdir(pwd, 'Select or create results folder.');
                
                % Refocus
                figure(app.MotionUI);
                
                % Enable clear option
                app.SaveDirectoryClearButton.Enable = 'On';
            catch
            end
        end

        % Value changed function: MotionSynchronisationSwitch
        function motionSynchronisationSwitchPressed(app, event)
            value = app.MotionSynchronisationSwitch.Value;
            
            if strcmp(value, 'Off')
                app.adjustMotionSynchronisationOptions(value, [1 1 1 1]);
                app.BaselineSelected = false;
            elseif strcmp(value, 'On')
                if app.NInputs() < 2
                    app.MotionSynchronisationSwitch.Value = 'Off';
                    str = ...
                        'Require 2 or more inputs for motion synchronisation.';
                    app.updateFeedbackPanel(str, 1);
                else
                    array = [false false false false];
                    if ~isempty(app.MarkersDataPath.Value)
                        array(1) = true;
                    end
                    if ~isempty(app.GRFDataPath.Value)
                        array(2) = true;
                    end
                    if ~isempty(app.EMGDataPath.Value)
                        array(3) = true;
                    end
                    if ~isempty(app.CalorimetryDataPath.Value)
                        array(4) = true;
                    end
                    app.adjustMotionSynchronisationOptions(value, array);
                end
            end
            
        end

        % Value changed function: SpeedCompensationSwitch
        function speedCompensationSwitchPressed(app, event)
            value = app.SpeedCompensationSwitch.Value;
            
            if strcmp(value, 'Off')
                app.adjustSpeedCompensationOptions(value);
            elseif strcmp(value, 'On')
                if isempty(app.MarkersDataPath.Value) && ...
                        isempty(app.GRFDataPath.Value)
                    app.SpeedCompensationSwitch.Value = 'Off';
                    str = 'Require marker or GRF data for motion compensation.';
                    app.updateFeedbackPanel(str, 1);
                else
                    app.adjustSpeedCompensationOptions(value);
                end
            end
            
        end

        % Value changed function: GaitSegmentationSwitch
        function gaitSegmentationSwitchPressed(app, event)
            value = app.GaitSegmentationSwitch.Value;
            
            if strcmp(value, 'Off')
                app.adjustGaitSegmentationOptions(value);
            elseif strcmp(value, 'On')
                if isempty(app.MarkersDataPath.Value) && ...
                        isempty(app.GRFDataPath.Value)
                    app.GaitSegmentationSwitch.Value = 'Off';
                    str = 'Require marker or GRF data for gait segmentation.';
                    app.updateFeedbackPanel(str, 1);
                else
                    app.adjustGaitSegmentationOptions(value);
                end
            end
        end

        % Button pushed function: GRFDataClearButton
        function clearGRFDataPath(app, event)
            % Clear data path
            app.GRFDataPath.Value = '';
            app.GRFDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
            % Disable GRF orientation options
            app.adjustGRFConfigurationOptions('Off');
        end

        % Button pushed function: EMGDataClearButton
        function clearEMGDataPath(app, event)
            % Clear data path
            app.EMGDataPath.Value = '';
            app.EMGDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
        end

        % Button pushed function: CalorimetryDataClearButton
        function clearCalorimetryDataPath(app, event)
            % Clear data path
            app.CalorimetryDataPath.Value = '';
            app.CalorimetryDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
        end

        % Button pushed function: SaveDirectoryClearButton
        function clearSaveDirectoryPath(app, event)
            % Clear data path
            app.SaveDirectoryPath.Value = '';
            app.SaveDirectoryClearButton.Enable = 'Off';
        end

        % Value changed function: MarkersCheckBox
        function markersChecked(app, event)
            
            app.adjustBaselineChecks([1 0 0 0]);
            app.MarkersDelay.Value = 0;
            app.BaselineSelected = true;
            
        end

        % Value changed function: GRFCheckBox
        function grfChecked(app, event)
            
            app.adjustBaselineChecks([0 1 0 0]);
            app.GRFDelay.Value = 0;
            app.BaselineSelected = true;
            
        end

        % Value changed function: EMGCheckBox
        function emgChecked(app, event)
            
            app.adjustBaselineChecks([0 0 1 0]);
            app.EMGDelay.Value = 0;
            app.BaselineSelected = true;
            
        end

        % Value changed function: CalorimetryCheckBox
        function calorimetryChecked(app, event)
            
            app.adjustBaselineChecks([0 0 0 1]);
            app.CalorimetryDelay.Value = 0;
            app.BaselineSelected = true;
            
        end

        % Value changed function: MarkersDelay
        function markerDelayModified(app, event)
            
            if app.MarkersCheckBox.Value
                app.MarkersDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str, 1);
            end
            
        end

        % Value changed function: GRFDelay
        function grfDelayModified(app, event)
            
            if app.GRFCheckBox.Value
                app.GRFDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str, 1);
            end
            
        end

        % Value changed function: EMGDelay
        function emgDelayModified(app, event)
            
            if app.EMGCheckBox.Value
                app.EMGDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str, 1);
            end
            
        end

        % Value changed function: CalorimetryDelay
        function calorimetryDelayModified(app, event)
            
            if app.CalorimetryCheckBox.Value
                app.CalorimetryDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str, 1);
            end
            
        end

        % Button pushed function: SubsetButton
        function chooseFileSubset(app, event)
            app.SubsetButton.Enable = 'Off';
            
            path = app.getFirstDataPath();
            
            if isempty(path)
                str = 'Require data to choose file subset.';
                app.updateFeedbackPanel(str, 1);
                app.FileSelectorButton.Enable = 'On';
            else
                [~, files] = dirNoDots(path);
            end
            app.FileSet = files;
            app.FileSelector = FileSelectorApp(app, files);
        end

        % Button pushed function: SpeedModeButton
        function changeCompensationMode(app, event)
            
             % Get button name
            name = app.SpeedModeButton.Text;
            
            % Different behaviour depending on 'pressing' vs 'unpressing'
            switch name
                case 'Load'
                    
                    % Use try-catch so user can cancel input without error
                    try
                        % Get and set marker data path
                        app.SpeedDataPath.Value = uigetdir(...
                            pwd, 'Select folder containing speed data.');
                    catch
                        return;
                    end
                    
                    % Change which components are enabled
                    enable_status = {'On', 'Off'};
                    
                    % Change button name
                    app.SpeedModeButton.Text = 'Unload';
     
                case 'Unload'
                    app.SpeedDataPath.Value = '';
                    
                    % Change which components are enabled
                    enable_status = {'Off', 'On'};
                    
                    % Change button name
                    app.SpeedModeButton.Text = 'Load';
            end
            
            % Change which components are enabled
            app.SpeedDataPath.Enable = enable_status{1};
            app.SpeedInput.Enable = enable_status{2};
            
        end

        % Button pushed function: ProcessButton
        function processData(app, event)
            
            % Require at least some data
            if app.NInputs < 1
                str = 'Require at least one data path for processing.';
                app.updateFeedbackPanel(str, 1);
                return;
            end
            
            % Require a designated save directory
            if isempty(app.SaveDirectoryPath.Value)
                str = 'Processing requires save directory.';
                app.updateFeedbackPanel(str, 1);
                return;
            end
            
            % Require user to choose baseline for motion synchronisation 
            if strcmp(app.MotionSynchronisationSwitch.Value, 'On') && ...
                    ~app.BaselineSelected
                str = 'Please select motion synchronisation baseline.';
                app.updateFeedbackPanel(str, 1);
                return;
            end
            
            % Create a struct to store data paths
            paths = struct();
            
            % Step through and store data paths 
            streams = {'Markers', 'GRF', 'EMG', 'Calorimetry'};
            for i = 1:length(streams)
                fieldname = [streams{i} 'DataPath'];
                if ~isempty(app.(fieldname).Value)
                    paths.(streams{i}) = app.(fieldname).Value;
                end
            end
            streams = fieldnames(paths);
            
            % Create a struct to store settings options
            settings = struct();
            
            % Store results path
            settings.SaveDirectory = app.SaveDirectoryPath.Value;
            
            % Step through storing processing settings
            
            % Motion Synchronisation
            if strcmp(app.MotionSynchronisationSwitch.Value, 'On')
                for i = 1:length(streams)
                    delay_str = [streams{i} 'Delay'];
                    baseline_str = [streams{i} 'CheckBox'];
                    settings.(delay_str) = app.(delay_str).Value;
                    if app.(baseline_str).Value 
                        settings.Baseline = streams{i};
                    end
                end
            end
            
            % GRF configuration
            if any(strcmp('GRF', streams))
                directions = {'Forward', 'Up', 'Right'};
                for i = 1:length(directions)
                    settings.GRFSystem.(directions{i}) = ...
                        app.(['GRF' directions{i} 'DropDown']).Value;
                end
                settings.Inclination = app.TiltEditField.Value;
            end
            
            % Gait segmentation
            if strcmp(app.GaitSegmentationSwitch.Value, 'On')
                settings.SegmentationMode = app.ModePanel.SelectedObject.Text;
                settings.Feet = app.FeetPanel.SelectedObject.Text;
            end
            
            % Speed compensation
            if strcmp(app.SpeedCompensationSwitch.Value, 'On')
                if strcmp(app.SpeedModeButton.Text, 'Load')
                    settings.Speed = app.SpeedInput.Value;
                else
                    settings.Speed = app.SpeedDataPath.Value;
                    settings.SpeedLabel = app.SpeedLabelEditField.Value;
                    settings.SpeedFilter = app.SpeedFilterEditField.Value;
                    settings.SpeedThreshold = app.SpeedThresholdCheckBox.Value;
                end
            end
            
            % Coordinate transformation
            if any(strcmp('Markers', streams))
                settings.CoordinateRotation = [app.XRotation.Value, ...
                    app.YRotation.Value, app.ZRotation.Value];
                settings.CoordinateTranslation = [app.XOffset.Value, ...
                    app.YOffset.Value, app.ZOffset.Value];
            end
            
            % Note detailed error status
            if strcmp(app.ErrorSwitch.Value, 'On')
                settings.info = true;
            else
                settings.info = false;
            end
            
            % Finally, pass paths & settings information to batch processor
            app.updateFeedbackPanel('Processing...', 0);
            drawnow;
            err = batchProcessData(paths, settings);
            if ~err
                app.updateFeedbackPanel('Processing complete.', 0);
            else
                app.updateFeedbackPanel(['Processing finished with errors - '...
                    'see MATLAB command window for more details.'], 1);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionUI = uifigure('Visible', 'off');
            app.MotionUI.Name = 'Motion Processor';
            app.OuterGrid = uigridlayout(app.MotionUI);
            app.MarkersDataButton = uibutton(app.OuterGrid, 'push');
            app.MarkersDataButton.ButtonPushedFcn = createCallbackFcn(app, @getMarkersDataPath, true);
            app.MarkersDataButton.Text = 'Marker Data';
            app.GRFDataButton = uibutton(app.OuterGrid, 'push');
            app.GRFDataButton.ButtonPushedFcn = createCallbackFcn(app, @getGRFDataPath, true);
            app.GRFDataButton.Text = 'GRF Data';
            app.EMGDataButton = uibutton(app.OuterGrid, 'push');
            app.EMGDataButton.ButtonPushedFcn = createCallbackFcn(app, @getEMGDataPath, true);
            app.EMGDataButton.Text = 'EMG Data';
            app.CalorimetryDataButton = uibutton(app.OuterGrid, 'push');
            app.CalorimetryDataButton.ButtonPushedFcn = createCallbackFcn(app, @getCalorimetryDataPath, true);
            app.CalorimetryDataButton.Text = 'Calorimetry Data';
            app.MarkersDataPath = uieditfield(app.OuterGrid, 'text');
            app.MarkersDataPath.Editable = 'off';
            app.GRFDataPath = uieditfield(app.OuterGrid, 'text');
            app.GRFDataPath.Editable = 'off';
            app.EMGDataPath = uieditfield(app.OuterGrid, 'text');
            app.EMGDataPath.Editable = 'off';
            app.CalorimetryDataPath = uieditfield(app.OuterGrid, 'text');
            app.CalorimetryDataPath.Editable = 'off';
            app.SubsetButton = uibutton(app.OuterGrid, 'push');
            app.SubsetButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileSubset, true);
            app.SubsetButton.Text = 'Choose Subset of Data Files';
            app.SubsetSelectedLabel = uilabel(app.OuterGrid);
            app.SubsetSelectedLabel.Text = 'Subset Selected?';
            app.SubsetIndicator = uilamp(app.OuterGrid);
            app.FooterToolbox = uilabel(app.OuterGrid);
            app.FooterToolbox.Text = 'Component of Gait Analysis Toolbox';
            app.FooterAuthor = uilabel(app.OuterGrid);
            app.FooterAuthor.Text = 'Daniel Gordon 2020';
            app.FeedbackPanel = uitextarea(app.OuterGrid);
            app.FeedbackPanel.Editable = 'off';
            app.SaveDirectoryButton = uibutton(app.OuterGrid, 'push');
            app.SaveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @getSaveDirectoryPath, true);
            app.SaveDirectoryButton.Text = 'Save Directory';
            app.SaveDirectoryPath = uieditfield(app.OuterGrid, 'text');
            app.SaveDirectoryPath.Editable = 'off';
            app.MarkersDataClearButton = uibutton(app.OuterGrid, 'push');
            app.MarkersDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearMarkersDataPath, true);
            app.MarkersDataClearButton.Enable = 'off';
            app.MarkersDataClearButton.Text = 'Clear';
            app.GRFDataClearButton = uibutton(app.OuterGrid, 'push');
            app.GRFDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearGRFDataPath, true);
            app.GRFDataClearButton.Enable = 'off';
            app.GRFDataClearButton.Text = 'Clear';
            app.EMGDataClearButton = uibutton(app.OuterGrid, 'push');
            app.EMGDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearEMGDataPath, true);
            app.EMGDataClearButton.Enable = 'off';
            app.EMGDataClearButton.Text = 'Clear';
            app.CalorimetryDataClearButton = uibutton(app.OuterGrid, 'push');
            app.CalorimetryDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearCalorimetryDataPath, true);
            app.CalorimetryDataClearButton.Enable = 'off';
            app.CalorimetryDataClearButton.Text = 'Clear';
            app.SaveDirectoryClearButton = uibutton(app.OuterGrid, 'push');
            app.SaveDirectoryClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearSaveDirectoryPath, true);
            app.SaveDirectoryClearButton.Enable = 'off';
            app.SaveDirectoryClearButton.Text = 'Clear';
            app.ProcessingTabs = uitabgroup(app.OuterGrid);
            app.HighLevelTab = uitab(app.ProcessingTabs);
            app.HighLevelTab.Title = 'High Level Processing';
            app.HighLevelGrid = uigridlayout(app.HighLevelTab);
            app.MotionCaptureConfigurationPanel = uipanel(app.HighLevelGrid);
            app.MotionCaptureConfigurationPanel.Title = 'Motion Capture Configuration';
            app.MotionCaptureConfigurationGrid = uigridlayout(app.MotionCaptureConfigurationPanel);
            app.TranslationLabel = uilabel(app.MotionCaptureConfigurationGrid);
            app.TranslationLabel.Text = 'Translation (m)';
            app.XEditFieldLabel_2 = uilabel(app.MotionCaptureConfigurationGrid);
            app.XEditFieldLabel_2.Enable = 'off';
            app.XEditFieldLabel_2.Text = 'X';
            app.XRotation = uieditfield(app.MotionCaptureConfigurationGrid, 'numeric');
            app.XRotation.ValueDisplayFormat = '%.2f';
            app.XRotation.Enable = 'off';
            app.YEditFieldLabel_2 = uilabel(app.MotionCaptureConfigurationGrid);
            app.YEditFieldLabel_2.Enable = 'off';
            app.YEditFieldLabel_2.Text = 'Y';
            app.YRotation = uieditfield(app.MotionCaptureConfigurationGrid, 'numeric');
            app.YRotation.ValueDisplayFormat = '%.2f';
            app.YRotation.Enable = 'off';
            app.ZEditFieldLabel_2 = uilabel(app.MotionCaptureConfigurationGrid);
            app.ZEditFieldLabel_2.Enable = 'off';
            app.ZEditFieldLabel_2.Text = 'Z';
            app.ZRotation = uieditfield(app.MotionCaptureConfigurationGrid, 'numeric');
            app.ZRotation.ValueDisplayFormat = '%.2f';
            app.ZRotation.Enable = 'off';
            app.RotationdegLabel = uilabel(app.MotionCaptureConfigurationGrid);
            app.RotationdegLabel.Text = 'Rotation (deg)';
            app.XEditFieldLabel = uilabel(app.MotionCaptureConfigurationGrid);
            app.XEditFieldLabel.Enable = 'off';
            app.XEditFieldLabel.Text = 'X';
            app.XOffset = uieditfield(app.MotionCaptureConfigurationGrid, 'numeric');
            app.XOffset.ValueDisplayFormat = '%.2f';
            app.XOffset.Enable = 'off';
            app.YEditFieldLabel = uilabel(app.MotionCaptureConfigurationGrid);
            app.YEditFieldLabel.Enable = 'off';
            app.YEditFieldLabel.Text = 'Y';
            app.YOffset = uieditfield(app.MotionCaptureConfigurationGrid, 'numeric');
            app.YOffset.ValueDisplayFormat = '%.2f';
            app.YOffset.Enable = 'off';
            app.ZEditFieldLabel = uilabel(app.MotionCaptureConfigurationGrid);
            app.ZEditFieldLabel.Enable = 'off';
            app.ZEditFieldLabel.Text = 'Z';
            app.ZOffset = uieditfield(app.MotionCaptureConfigurationGrid, 'numeric');
            app.ZOffset.ValueDisplayFormat = '%.2f';
            app.ZOffset.Enable = 'off';
            app.ForcePlateConfigurationPanel = uipanel(app.HighLevelGrid);
            app.ForcePlateConfigurationPanel.Title = 'Force Plate Configuration';
            app.ForcePlateConfigurationGrid = uigridlayout(app.ForcePlateConfigurationPanel);
            app.ForwardLabel = uilabel(app.ForcePlateConfigurationGrid);
            app.ForwardLabel.Text = 'Forward';
            app.UpLabel = uilabel(app.ForcePlateConfigurationGrid);
            app.UpLabel.Text = 'Up';
            app.RightLabel = uilabel(app.ForcePlateConfigurationGrid);
            app.RightLabel.Text = 'Right';
            app.GRFForwardDropDown = uidropdown(app.ForcePlateConfigurationGrid);
            app.GRFForwardDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRFForwardDropDown.Enable = 'off';
            app.GRFForwardDropDown.Value = '+y';
            app.GRFUpDropDown = uidropdown(app.ForcePlateConfigurationGrid);
            app.GRFUpDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRFUpDropDown.Enable = 'off';
            app.GRFUpDropDown.Value = '-z';
            app.GRFRightDropDown = uidropdown(app.ForcePlateConfigurationGrid);
            app.GRFRightDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRFRightDropDown.Enable = 'off';
            app.GRFRightDropDown.Value = '-x';
            app.TiltEditField = uieditfield(app.ForcePlateConfigurationGrid, 'numeric');
            app.TiltEditField.Editable = 'off';
            app.TiltEditField.Enable = 'off';
            app.TiltdegLabel = uilabel(app.ForcePlateConfigurationGrid);
            app.TiltdegLabel.Text = 'Tilt (deg)';
            app.MotionSynchronisationPanel = uipanel(app.HighLevelGrid);
            app.MotionSynchronisationPanel.Title = 'Motion Synchronisation';
            app.MotionSynchronisationGrid = uigridlayout(app.MotionSynchronisationPanel);
            app.BaselineLabel = uilabel(app.MotionSynchronisationGrid);
            app.BaselineLabel.Text = 'Baseline';
            app.TimeDelaysLabel = uilabel(app.MotionSynchronisationGrid);
            app.TimeDelaysLabel.Text = 'Time Delay (s)';
            app.MarkersCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.MarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @markersChecked, true);
            app.MarkersCheckBox.Enable = 'off';
            app.MarkersCheckBox.Text = 'Markers';
            app.GRFCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.GRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @grfChecked, true);
            app.GRFCheckBox.Enable = 'off';
            app.GRFCheckBox.Text = 'GRF';
            app.EMGCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.EMGCheckBox.ValueChangedFcn = createCallbackFcn(app, @emgChecked, true);
            app.EMGCheckBox.Enable = 'off';
            app.EMGCheckBox.Text = 'EMG';
            app.CalorimetryCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.CalorimetryCheckBox.ValueChangedFcn = createCallbackFcn(app, @calorimetryChecked, true);
            app.CalorimetryCheckBox.Enable = 'off';
            app.CalorimetryCheckBox.Text = 'Calorimetry';
            app.MarkersDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.MarkersDelay.ValueDisplayFormat = '%.2f';
            app.MarkersDelay.ValueChangedFcn = createCallbackFcn(app, @markerDelayModified, true);
            app.MarkersDelay.Enable = 'off';
            app.GRFDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.GRFDelay.ValueDisplayFormat = '%.2f';
            app.GRFDelay.ValueChangedFcn = createCallbackFcn(app, @grfDelayModified, true);
            app.GRFDelay.Enable = 'off';
            app.EMGDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.EMGDelay.ValueDisplayFormat = '%.2f';
            app.EMGDelay.ValueChangedFcn = createCallbackFcn(app, @emgDelayModified, true);
            app.EMGDelay.Enable = 'off';
            app.CalorimetryDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.CalorimetryDelay.ValueDisplayFormat = '%.2f';
            app.CalorimetryDelay.ValueChangedFcn = createCallbackFcn(app, @calorimetryDelayModified, true);
            app.CalorimetryDelay.Enable = 'off';
            app.GaitSegmentationPanel = uipanel(app.HighLevelGrid);
            app.GaitSegmentationPanel.Title = 'Gait Segmentation';
            app.GaitSegmentationGrid = uigridlayout(app.GaitSegmentationPanel);
            app.ModePanel = uibuttongroup(app.GaitSegmentationGrid);
            app.ModePanel.Title = 'Mode';
            app.StanceButton = uiradiobutton(app.ModePanel);
            app.StanceButton.Enable = 'off';
            app.StanceButton.Text = 'Stance';
            app.StanceButton.Value = true;
            app.KinematicsButton = uiradiobutton(app.ModePanel);
            app.KinematicsButton.Enable = 'off';
            app.KinematicsButton.Text = 'Kinematics';
            app.FeetPanel = uibuttongroup(app.GaitSegmentationGrid);
            app.FeetPanel.Title = 'Feet';
            app.LeftButton = uiradiobutton(app.FeetPanel);
            app.LeftButton.Enable = 'off';
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.FeetPanel);
            app.RightButton.Enable = 'off';
            app.RightButton.Text = 'Right';
            app.BothButton = uiradiobutton(app.FeetPanel);
            app.BothButton.Enable = 'off';
            app.BothButton.Text = 'Both';
            app.SpeedCompensationPanel = uipanel(app.HighLevelGrid);
            app.SpeedCompensationPanel.Title = 'Speed Compensation';
            app.SpeedCompensationGrid = uigridlayout(app.SpeedCompensationPanel);
            app.SpeedDataPath = uieditfield(app.SpeedCompensationGrid, 'text');
            app.SpeedDataPath.Editable = 'off';
            app.SpeedDataPath.Enable = 'off';
            app.SpeedModeButton = uibutton(app.SpeedCompensationGrid, 'push');
            app.SpeedModeButton.ButtonPushedFcn = createCallbackFcn(app, @changeCompensationMode, true);
            app.SpeedModeButton.Enable = 'off';
            app.SpeedModeButton.Text = 'Load';
            app.SpeedmsEditFieldLabel = uilabel(app.SpeedCompensationGrid);
            app.SpeedmsEditFieldLabel.Text = 'Speed (m/s)';
            app.SpeedInput = uieditfield(app.SpeedCompensationGrid, 'numeric');
            app.SpeedInput.ValueDisplayFormat = '%.2f';
            app.SpeedInput.Enable = 'off';
            app.DataProcessingOptionsPanel = uipanel(app.HighLevelGrid);
            app.DataProcessingOptionsPanel.Title = 'Data Processing Options';
            app.DataProcessingOptionsGrid = uigridlayout(app.DataProcessingOptionsPanel);
            app.MotionSynchronisationLabel = uilabel(app.DataProcessingOptionsGrid);
            app.MotionSynchronisationLabel.Text = 'Motion Synchronisation';
            app.MotionSynchronisationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.MotionSynchronisationSwitch.ValueChangedFcn = createCallbackFcn(app, @motionSynchronisationSwitchPressed, true);
            app.SpeedCompensationSwitchLabel = uilabel(app.DataProcessingOptionsGrid);
            app.SpeedCompensationSwitchLabel.Text = 'Speed Compensation';
            app.SpeedCompensationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.SpeedCompensationSwitch.ValueChangedFcn = createCallbackFcn(app, @speedCompensationSwitchPressed, true);
            app.GaitSegmentationLabel = uilabel(app.DataProcessingOptionsGrid);
            app.GaitSegmentationLabel.Text = 'Gait Segmentation';
            app.GaitSegmentationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.GaitSegmentationSwitch.ValueChangedFcn = createCallbackFcn(app, @gaitSegmentationSwitchPressed, true);
            app.ApplicationPanel = uipanel(app.HighLevelGrid);
            app.ApplicationPanel.Title = 'Application';
            app.DebuggingGrid = uigridlayout(app.ApplicationPanel);
            app.ErrorSwitch = uiswitch(app.DebuggingGrid, 'slider');
            app.ErrorInfoLabel = uilabel(app.DebuggingGrid);
            app.ErrorInfoLabel.Text = 'Error Info';
            app.SaveProcessingSettingsButton = uibutton(app.DebuggingGrid, 'push');
            app.SaveProcessingSettingsButton.Text = 'Save Processing Settings';
            app.LowLevelTab = uitab(app.ProcessingTabs);
            app.LowLevelTab.Title = 'Low Level Processing';
            app.LowLevelGrid = uigridlayout(app.LowLevelTab);
            app.SpeedCompensationPanel_2 = uipanel(app.LowLevelGrid);
            app.SpeedCompensationPanel_2.Title = 'Speed Compensation';
            app.LowLevelSpeedCompensationGrid = uigridlayout(app.SpeedCompensationPanel_2);
            app.SpeedLabelEditFieldLabel = uilabel(app.LowLevelSpeedCompensationGrid);
            app.SpeedLabelEditFieldLabel.Text = 'Speed Label';
            app.SpeedLabelEditField = uieditfield(app.LowLevelSpeedCompensationGrid, 'text');
            app.SpeedLabelEditField.Value = 'Speed';
            app.FilterEditFieldLabel = uilabel(app.LowLevelSpeedCompensationGrid);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.SpeedFilterEditField = uieditfield(app.LowLevelSpeedCompensationGrid, 'numeric');
            app.SpeedFilterEditField.Value = 6;
            app.SpeedThresholdCheckBox = uicheckbox(app.LowLevelSpeedCompensationGrid);
            app.SpeedThresholdCheckBox.Text = '';
            app.SpeedThresholdCheckBox.Value = true;
            app.SpeedThresholdLabel = uilabel(app.LowLevelSpeedCompensationGrid);
            app.SpeedThresholdLabel.Text = 'Threshold ';
            app.EMGProcessingPanel = uipanel(app.LowLevelGrid);
            app.EMGProcessingPanel.Title = 'EMG Processing';
            app.GridLayout = uigridlayout(app.EMGProcessingPanel);
            app.FilterOrderEditFieldLabel = uilabel(app.GridLayout);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.GridLayout, 'numeric');
            app.FilterOrderEditField.Value = 2;
            app.BPLowerCutoffEditFieldLabel = uilabel(app.GridLayout);
            app.BPLowerCutoffEditFieldLabel.Text = 'BP Lower Cutoff';
            app.BPLowerCutoffEditField = uieditfield(app.GridLayout, 'numeric');
            app.BPLowerCutoffEditField.Value = 30;
            app.BPUpperCutoffEditFieldLabel = uilabel(app.GridLayout);
            app.BPUpperCutoffEditFieldLabel.Text = 'BP Upper Cutoff';
            app.BPUpperCutoffEditField = uieditfield(app.GridLayout, 'numeric');
            app.BPUpperCutoffEditField.Value = 300;
            app.LPCutoffEditFieldLabel = uilabel(app.GridLayout);
            app.LPCutoffEditFieldLabel.Text = 'LP Cutoff';
            app.LPCutoffEditField = uieditfield(app.GridLayout, 'numeric');
            app.LPCutoffEditField.Value = 6;
            app.GRFProcessingPanel = uipanel(app.LowLevelGrid);
            app.GRFProcessingPanel.Title = 'GRF Processing';
            app.FileParsingPanel = uipanel(app.LowLevelGrid);
            app.FileParsingPanel.Title = 'File Parsing';
            app.GridLayout2 = uigridlayout(app.FileParsingPanel);
            app.LoadGRFMapButton = uibutton(app.GridLayout2, 'push');
            app.LoadGRFMapButton.Text = 'Load GRF Map';
            app.LoadEMGMapButton = uibutton(app.GridLayout2, 'push');
            app.LoadEMGMapButton.Text = 'Load EMG Map';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField_2 = uieditfield(app.GridLayout2, 'text');
            app.ProcessButton = uibutton(app.OuterGrid, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processData, true);
            app.ProcessButton.Text = 'Process';
            % Show the figure after all components are created
            app.MotionUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionProcessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionUI)
        end
    end
end