classdef ProcessMotionData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionDataProcessingAppUIFigure  matlab.ui.Figure
        ChooseAnalysisModeDropDownLabel  matlab.ui.control.Label
        ChooseAnalysisModeDropDown     matlab.ui.control.DropDown
        MarkerDataFolderButton         matlab.ui.control.Button
        MarkerFolder                   matlab.ui.control.EditField
        GRFDataFolderButton            matlab.ui.control.Button
        GRFFolder                      matlab.ui.control.EditField
        SaveDirectoryButton            matlab.ui.control.Button
        SaveDirectory                  matlab.ui.control.EditField
        DisplayDetailedInfoCheckBox    matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        CustomTab                      matlab.ui.container.Tab
        MarkerRotationsLabel_2         matlab.ui.control.Label
        GRFRotationsLabel_2            matlab.ui.control.Label
        DataTimeDelayEditField_2Label_2  matlab.ui.control.Label
        DataTimeDelayEditField_Custom  matlab.ui.control.NumericEditField
        CutoffLabel                    matlab.ui.control.Label
        StanceCutoffEditField_Custom   matlab.ui.control.NumericEditField
        FeetLabel_2                    matlab.ui.control.Label
        FeetDropDown_Custom            matlab.ui.control.DropDown
        SegmentationModeButtonGroup_Custom  matlab.ui.container.ButtonGroup
        StanceButton_Custom            matlab.ui.control.RadioButton
        ToepeakButton_Custom           matlab.ui.control.RadioButton
        XEditFieldLabel_2              matlab.ui.control.Label
        Marker_X_Custom                matlab.ui.control.NumericEditField
        YEditFieldLabel_2              matlab.ui.control.Label
        Marker_Y_Custom                matlab.ui.control.NumericEditField
        ZEditFieldLabel_2              matlab.ui.control.Label
        Marker_Z_Custom                matlab.ui.control.NumericEditField
        XEditField_2Label_2            matlab.ui.control.Label
        GRF_X_Custom                   matlab.ui.control.NumericEditField
        YEditField_2Label_2            matlab.ui.control.Label
        GRF_Y_Custom                   matlab.ui.control.NumericEditField
        ZEditField_2Label_2            matlab.ui.control.Label
        GRF_Z_Custom                   matlab.ui.control.NumericEditField
        MotionSpeedEditFieldLabel      matlab.ui.control.Label
        MotionSpeedEditField_Custom    matlab.ui.control.NumericEditField
        MotionTiltEditFieldLabel       matlab.ui.control.Label
        MotionTiltEditField_Custom     matlab.ui.control.NumericEditField
        MotionDirectionLabel           matlab.ui.control.Label
        MotionDirectionEditField_Custom  matlab.ui.control.DropDown
        MarkerLeftHanded_Custom        matlab.ui.control.CheckBox
        GRFLeftHanded_Custom           matlab.ui.control.CheckBox
        UoETab                         matlab.ui.container.Tab
        DataTimeDelayEditField_2Label  matlab.ui.control.Label
        DataTimeDelayEditField_UoE     matlab.ui.control.NumericEditField
        MarkerRotationsLabel           matlab.ui.control.Label
        GRFRotationsLabel              matlab.ui.control.Label
        XEditFieldLabel                matlab.ui.control.Label
        Marker_X_UoE                   matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        Marker_Y_UoE                   matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        Marker_Z_UoE                   matlab.ui.control.NumericEditField
        XEditField_2Label              matlab.ui.control.Label
        GRF_X_UoE                      matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        GRF_Y_UoE                      matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        GRF_Z_UoE                      matlab.ui.control.NumericEditField
        SegmentationModeButtonGroup_UoE  matlab.ui.container.ButtonGroup
        StanceButton_UoE               matlab.ui.control.RadioButton
        ToepeakButton_UoE              matlab.ui.control.RadioButton
        CutoffLabel_2                  matlab.ui.control.Label
        StanceCutoffEditField_UoE      matlab.ui.control.NumericEditField
        FeetLabel_3                    matlab.ui.control.Label
        FeetDropDown_UoE               matlab.ui.control.DropDown
        MotionSpeedEditField_2Label    matlab.ui.control.Label
        MotionSpeedEditField_UoE       matlab.ui.control.NumericEditField
        MotionTiltEditField_2Label     matlab.ui.control.Label
        MotionTiltEditField_UoE        matlab.ui.control.NumericEditField
        MotionDirectionLabel_2         matlab.ui.control.Label
        MotionDirectionEditField_UoE   matlab.ui.control.DropDown
        MarkerLeftHanded_UoE           matlab.ui.control.CheckBox
        GRFLeftHanded_UoE              matlab.ui.control.CheckBox
        ProcessButton                  matlab.ui.control.Button
        SegmentationSwitchLabel        matlab.ui.control.Label
        SegmentationSwitch             matlab.ui.control.Switch
        TextArea                       matlab.ui.control.TextArea
        ComponentofGaitAnalysisToolboxLabel  matlab.ui.control.Label
        DanielGordon2019Label          matlab.ui.control.Label
        DefaultSaveFoldersCheckBox     matlab.ui.control.CheckBox
        GRFEditFieldLabel              matlab.ui.control.Label
        GRFEditField                   matlab.ui.control.EditField
        MarkersEditFieldLabel          matlab.ui.control.Label
        MarkersEditField               matlab.ui.control.EditField
        MotionCompensationSwitchLabel  matlab.ui.control.Label
        MotionCompensationSwitch       matlab.ui.control.Switch
        SpeedDataFolderButton          matlab.ui.control.Button
        SpeedFolder                    matlab.ui.control.EditField
        AutocropCheckBox               matlab.ui.control.CheckBox
    end


    methods (Access = private)
    
        function setGRFOptions(app, status)
            
            set(app.GRFDataFolderButton, 'Enable', status);
            set(app.GRF_X_UoE, 'Enable', status);
            set(app.GRF_Y_UoE, 'Enable', status);
            set(app.GRF_Z_UoE, 'Enable', status);
            set(app.GRF_X_Custom, 'Enable', status);
            set(app.GRF_Y_Custom, 'Enable', status);
            set(app.GRF_Z_Custom, 'Enable', status);
            set(app.GRFLeftHanded_UoE, 'Enable', status);
            set(app.GRFLeftHanded_Custom, 'Enable', status);
            
        end
    
        function setSegmentationOptions(app, status)

            setStanceOptions(app, status);
            setToeOptions(app, status);
            set(app.StanceCutoffEditField_UoE, 'Enable', status);
            set(app.StanceCutoffEditField_Custom, 'Enable', status);
            set(app.FeetDropDown_UoE, 'Enable', status);
            set(app.FeetDropDown_Custom, 'Enable', status);
            switch app.ChooseAnalysisModeDropDown.Value
                case 'Marker'
                    setStanceOptions(app, 'Off');
                    setToeOptions(app, status);
                case 'GRF'
                    setToeOptions(app, 'Off');
                    setStanceOptions(app, status);
                case 'Motion'
                    setToeOptions(app, status);
                    setStanceOptions(app, status);
            end
            
        end
    
        function setCompensationOptions(app, status)
            
            set(app.MotionDirectionEditField_Custom, 'Enable', status);
            set(app.MotionDirectionEditField_UoE, 'Enable', status);
            set(app.MotionSpeedEditField_Custom, 'Enable', status);
            set(app.MotionSpeedEditField_UoE, 'Enable', status);
            set(app.MotionTiltEditField_Custom, 'Enable', status);
            set(app.MotionTiltEditField_UoE, 'Enable', status);
            set(app.SpeedDataFolderButton, 'Enable', status);
            
            switch app.ChooseAnalysisModeDropDown.Value
                case 'Marker'
                    set(app.MotionTiltEditField_Custom, 'Enable', 'Off');
                    set(app.MotionTiltEditField_UoE, 'Enable', 'Off');
            end
            
        end
    
        function setStanceOptions(app, status)
            
            set(app.StanceButton_UoE, 'Enable', status);
            set(app.StanceButton_Custom, 'Enable', status);
            set(app.StanceCutoffEditField_UoE, 'Enable', status);
            set(app.StanceCutoffEditField_Custom, 'Enable', status);
            
        end
    
        function setToeOptions(app, status)
            
            set(app.ToepeakButton_UoE, 'Enable', status);
            set(app.ToepeakButton_Custom, 'Enable', status);
        end
    
        function setMarkerOptions(app, status)
            
            set(app.MarkerDataFolderButton, 'Enable', status);
            set(app.Marker_X_UoE, 'Enable', status);
            set(app.Marker_Y_UoE, 'Enable', status);
            set(app.Marker_Z_UoE, 'Enable', status);
            set(app.Marker_X_Custom, 'Enable', status);
            set(app.Marker_Y_Custom, 'Enable', status);
            set(app.Marker_Z_Custom, 'Enable', status);
            set(app.MarkerLeftHanded_UoE, 'Enable', status);
            set(app.MarkerLeftHanded_Custom, 'Enable', status);
            
        end
    
        function setSynchronisationOptions(app, status)
           
            set(app.DataTimeDelayEditField_Custom, 'Enable', status);
            set(app.DataTimeDelayEditField_UoE, 'Enable', status);
            
        end
    
        function base = getBestStartPath(app)
            
            possibilities = {app.MarkerFolder.Value, app.GRFFolder.Value, app.SaveDirectory.Value};
            base = [];
            for i=1:length(possibilities)
                if ~isempty(possibilities{i})
                    components = strsplit(possibilities{i}, filesep);
                    base = strjoin(components(1:end-1), filesep);
                    break;
                end
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableOptions(app)
            setSegmentationOptions(app, 'off')
        end

        % Button pushed function: MarkerDataFolderButton
        function getMarkerFolder(app, event)
            base = getBestStartPath(app);
            try
                app.MarkerFolder.Value = uigetdir(base, 'Select folder containing marker data');
            catch 
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end

        % Value changed function: ChooseAnalysisModeDropDown
        function disableOptions(app, event)
            value = app.ChooseAnalysisModeDropDown.Value;
            switch value
                case 'Static'
                    set(app.MarkersEditFieldLabel, 'Text', 'Static');
                    set(app.GRFEditField, 'Enable', 'off');
                    set(app.MarkersEditField, 'Enable', 'on');
                    set(app.SegmentationSwitch, 'Enable', 'off');
                    set(app.MotionCompensationSwitch, 'Enable', 'off');
                    setGRFOptions(app, 'off');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'off');
                    setCompensationOptions(app, 'off');
                    set(app.SpeedDataFolderButton, 'Enable', 'off');
                case 'Marker'
                    set(app.MarkersEditFieldLabel, 'Text', 'Markers');
                    set(app.GRFEditField, 'Enable', 'off');
                    set(app.MarkersEditField, 'Enable', 'on');
                    app.StanceButton_Custom.Value = 0;
                    app.StanceButton_UoE.Value = 0;
                    set(app.SegmentationSwitch, 'Enable', 'on');
                    set(app.MotionCompensationSwitch, 'Enable', 'on');
                    setGRFOptions(app, 'off');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'off');
                    setSegmentationOptions(app, app.SegmentationSwitch.Value);
                    setCompensationOptions(app, app.MotionCompensationSwitch.Value);
                    isempty(app.SpeedFolder.Value)
                case 'GRF'
                    set(app.MarkersEditFieldLabel, 'Text', 'Markers');
                    set(app.GRFEditField, 'Enable', 'on');
                    set(app.MarkersEditField, 'Enable', 'off');
                    app.StanceButton_Custom.Value = 1;
                    app.StanceButton_UoE.Value = 1;
                    set(app.SegmentationSwitch, 'Enable', 'on');
                    set(app.MotionCompensationSwitch, 'Enable', 'on');
                    setGRFOptions(app, 'on');
                    setMarkerOptions(app, 'off');
                    setSynchronisationOptions(app, 'off');
                    setSegmentationOptions(app, app.SegmentationSwitch.Value);
                    setCompensationOptions(app, app.MotionCompensationSwitch.Value);
                case 'Motion'
                    set(app.MarkersEditFieldLabel, 'Text', 'Markers');
                    set(app.GRFEditField, 'Enable', 'on');
                    set(app.MarkersEditField, 'Enable', 'on');
                    set(app.SegmentationSwitch, 'Enable', 'on');
                    set(app.MotionCompensationSwitch, 'Enable', 'on');
                    setGRFOptions(app, 'on');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'on');
                    setSegmentationOptions(app, app.SegmentationSwitch.Value);
                    setCompensationOptions(app, app.MotionCompensationSwitch.Value);
            end
        end

        % Value changed function: SegmentationSwitch
        function allowSegmentation(app, event)
            setSegmentationOptions(app, app.SegmentationSwitch.Value)
        end

        % Button pushed function: GRFDataFolderButton
        function getGRFData(app, event)
            base = getBestStartPath(app);
            try
                app.GRFFolder.Value = uigetdir(base, 'Select folder containing GRF data');
            catch
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end

        % Button pushed function: SaveDirectoryButton
        function getOrCreateResultsDir(app, event)
            base = getBestStartPath(app);
            try
                app.SaveDirectory.Value = uigetdir(base, 'Select or create results folder');
            catch
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end

        % Button pushed function: ProcessButton
        function processData(app, event)
            if isempty(app.SaveDirectory.Value)
                app.TextArea.Value = 'Processing requires save directory.';
            end
            
            % Default folder names.
            if app.DefaultSaveFoldersCheckBox.Value
                settings.static_folder = 'Static';
                settings.marker_folder = 'Markers';
                settings.grf_folder = 'GRF';
            else
                settings.static_folder = app.MarkersEditField.Value;
                settings.marker_folder = app.MarkersEditField.Value;
                settings.grf_folder = app.GRFEditField.Value;
            end
            
            settings.analysis = app.ChooseAnalysisModeDropDown.Value;
            settings.save_dir = app.SaveDirectory.Value;
            settings.info = app.DisplayDetailedInfoCheckBox.Value;
            type = app.TabGroup.SelectedTab.Title;
            switch app.ChooseAnalysisModeDropDown.Value
                case 'Static'
                    if isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Static processing requires marker data folder.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_rotations{1} = app.(['Marker_X_' type]).Value;
                        settings.marker_rotations{2} = app.(['Marker_Y_' type]).Value;
                        settings.marker_rotations{3} = app.(['Marker_Z_' type]).Value;
                        settings.marker_left_handed = app.(['MarkerLeftHanded_' type]).Value;
                    end
                case 'Marker'
                    if isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Marker processing requires marker data folder.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_rotations{1} = app.(['Marker_X_' type]).Value;
                        settings.marker_rotations{2} = app.(['Marker_Y_' type]).Value;
                        settings.marker_rotations{3} = app.(['Marker_Z_' type]).Value;
                        settings.marker_left_handed = app.(['MarkerLeftHanded_' type]).Value;
                    end
                case 'GRF'
                    if isempty(app.GRFFolder.Value)
                        app.TextArea.Value = 'GRF processing requires GRF data folder.';
                        return
                    else
                        settings.grfs = app.GRFFolder.Value;
                        settings.grf_rotations{1} = app.(['GRF_X_' type]).Value;
                        settings.grf_rotations{2} = app.(['GRF_Y_' type]).Value;
                        settings.grf_rotations{3} = app.(['GRF_Z_' type]).Value;
                        settings.grf_left_handed = app.(['GRFLeftHanded_' type]).Value;
                    end
                case 'Motion'
                    if isempty(app.GRFFolder.Value) || isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Motion processing requires marker + GRF data folders.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_rotations{1} = app.(['Marker_X_' type]).Value;
                        settings.marker_rotations{2} = app.(['Marker_Y_' type]).Value;
                        settings.marker_rotations{3} = app.(['Marker_Z_' type]).Value;
                        settings.grfs = app.GRFFolder.Value;
                        settings.grf_rotations{1} = app.(['GRF_X_' type]).Value;
                        settings.grf_rotations{2} = app.(['GRF_Y_' type]).Value;
                        settings.grf_rotations{3} = app.(['GRF_Z_' type]).Value;
                        settings.time_delay = app.(['DataTimeDelayEditField_' type]).Value;
                        settings.marker_left_handed = app.(['MarkerLeftHanded_' type]).Value;
                        settings.grf_left_handed = app.(['GRFLeftHanded_' type]).Value;
                    end
            end
            app.TextArea.Value = 'Processing...';
            drawnow;
            if strcmp(app.SegmentationSwitch.Value, 'On') && ~strcmp(app.ChooseAnalysisModeDropDown.Value, 'Static')
                if app.(['StanceButton_' type]).Value
                    settings.mode = 'stance';
                else
                    settings.mode = 'toe-peak';
                end
                settings.cutoff = app.(['StanceCutoffEditField_' type]).Value;
                if strcmp(app.(['FeetDropDown_' type]).Value, 'Left')
                    settings.feet = {'left'};
                elseif strcmp(app.(['FeetDropDown_' type]).Value, 'Right')
                    settings.feet = {'right'};
                else
                    settings.feet = {'left', 'right'};
                end
            end
            
            if strcmp(app.MotionCompensationSwitch.Value, 'On') && ~strcmp(app.ChooseAnalysisModeDropDown.Value, 'Static')
                if strcmp(app.(['MotionDirectionEditField_' type]).Value, 'X')
                    settings.direction = 'x';
                elseif strcmp(app.(['MotionDirectionEditField_' type]).Value, 'Y')
                    settings.direction = 'y';
                else
                    settings.direction = 'z';
                end
                if isempty(app.SpeedFolder.Value)
                    settings.speed = app.(['MotionSpeedEditField_' type]).Value;
                else
                    settings.speed = app.SpeedFolder.Value;
                end
                if ~strcmp(app.ChooseAnalysisModeDropDown.Value, 'Marker')
                    % do tilt stuff
                end
            end
            
            % Control auto-crop behaviour.
            settings.crop = app.AutocropCheckBox.Value;
            
            err = batchProcessData(settings);
            if ~err
                app.TextArea.Value = 'Processing complete.';
            else
                app.TextArea.Value = 'Processing finished with errors - see MATLAB command window for more details.';
            end
        end

        % Selection changed function: 
        % SegmentationModeButtonGroup_Custom
        function disableCutoffCustom(app, event)
            if app.ToepeakButton_Custom.Value
                set(app.StanceCutoffEditField_Custom, 'enable', 'off');
            else
                set(app.StanceCutoffEditField_Custom, 'enable', 'on');
            end
            
        end

        % Selection changed function: 
        % SegmentationModeButtonGroup_UoE
        function disableCutoffUoE(app, event)
            if app.ToepeakButton_UoE.Value
                set(app.StanceCutoffEditField_UoE, 'enable', 'off');
            else
                set(app.StanceCutoffEditField_UoE, 'enable', 'on');
            end
            
        end

        % Callback function
        function datasetOptions2(app, event)
            value = app.StructureDataCheckBox.Value;
            if value
                set(app.DatasetDescriptorButton, 'Enable', 'On');
            else
                set(app.DatasetDescriptorButton, 'Enable', 'Off');
            end
        end

        % Value changed function: DefaultSaveFoldersCheckBox
        function allowSaveChanges(app, event)
            value = app.DefaultSaveFoldersCheckBox.Value;
            if ~value
                set(app.GRFEditField, 'Visible', 'on');
                set(app.MarkersEditField, 'Visible', 'on');
                set(app.GRFEditFieldLabel, 'Visible', 'on');
                set(app.MarkersEditFieldLabel, 'Visible', 'on');
            else
                set(app.GRFEditField, 'Visible', 'off');
                set(app.MarkersEditField, 'Visible', 'off');
                set(app.GRFEditFieldLabel, 'Visible', 'off');
                set(app.MarkersEditFieldLabel, 'Visible', 'off');
            end
        end

        % Value changed function: MotionCompensationSwitch
        function allowCompensation(app, event)
            setCompensationOptions(app, app.MotionCompensationSwitch.Value)
        end

        % Button pushed function: SpeedDataFolderButton
        function getSpeedData(app, event)
            base = getBestStartPath(app);
            try
                app.SpeedFolder.Value = uigetdir(base, 'Select folder containing GRF data');
            catch
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionDataProcessingAppUIFigure = uifigure('Visible', 'off');
            app.MotionDataProcessingAppUIFigure.Name = 'Motion Data Processing App';
            app.ChooseAnalysisModeDropDownLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.ChooseAnalysisModeDropDownLabel.Text = 'Choose Analysis Mode';
            app.ChooseAnalysisModeDropDown = uidropdown(app.MotionDataProcessingAppUIFigure);
            app.ChooseAnalysisModeDropDown.Items = {'Static', 'Marker', 'GRF', 'Motion'};
            app.ChooseAnalysisModeDropDown.ValueChangedFcn = createCallbackFcn(app, @disableOptions, true);
            app.ChooseAnalysisModeDropDown.Value = 'Static';
            app.MarkerDataFolderButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.MarkerDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getMarkerFolder, true);
            app.MarkerDataFolderButton.Text = 'Marker Data Folder';
            app.MarkerFolder = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.MarkerFolder.Editable = 'off';
            app.GRFDataFolderButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.GRFDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getGRFData, true);
            app.GRFDataFolderButton.Text = 'GRF Data Folder';
            app.GRFFolder = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.GRFFolder.Editable = 'off';
            app.SaveDirectoryButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.SaveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @getOrCreateResultsDir, true);
            app.SaveDirectoryButton.Text = 'Save Directory';
            app.SaveDirectory = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.SaveDirectory.Editable = 'off';
            app.DisplayDetailedInfoCheckBox = uicheckbox(app.MotionDataProcessingAppUIFigure);
            app.DisplayDetailedInfoCheckBox.Text = 'Display Detailed Info';
            app.TabGroup = uitabgroup(app.MotionDataProcessingAppUIFigure);
            app.CustomTab = uitab(app.TabGroup);
            app.CustomTab.Title = 'Custom';
            app.MarkerRotationsLabel_2 = uilabel(app.CustomTab);
            app.MarkerRotationsLabel_2.Text = 'Marker Rotations';
            app.GRFRotationsLabel_2 = uilabel(app.CustomTab);
            app.GRFRotationsLabel_2.Text = 'GRF Rotations';
            app.DataTimeDelayEditField_2Label_2 = uilabel(app.CustomTab);
            app.DataTimeDelayEditField_2Label_2.Text = 'Data Time Delay';
            app.DataTimeDelayEditField_Custom = uieditfield(app.CustomTab, 'numeric');
            app.CutoffLabel = uilabel(app.CustomTab);
            app.CutoffLabel.Text = 'Cutoff';
            app.StanceCutoffEditField_Custom = uieditfield(app.CustomTab, 'numeric');
            app.StanceCutoffEditField_Custom.Value = 2;
            app.FeetLabel_2 = uilabel(app.CustomTab);
            app.FeetLabel_2.Text = 'Feet';
            app.FeetDropDown_Custom = uidropdown(app.CustomTab);
            app.FeetDropDown_Custom.Items = {'Left', 'Right', 'Both'};
            app.FeetDropDown_Custom.Value = 'Left';
            app.SegmentationModeButtonGroup_Custom = uibuttongroup(app.CustomTab);
            app.SegmentationModeButtonGroup_Custom.SelectionChangedFcn = createCallbackFcn(app, @disableCutoffCustom, true);
            app.SegmentationModeButtonGroup_Custom.Title = 'Segmentation Mode';
            app.StanceButton_Custom = uiradiobutton(app.SegmentationModeButtonGroup_Custom);
            app.StanceButton_Custom.Text = 'Stance';
            app.StanceButton_Custom.Value = true;
            app.ToepeakButton_Custom = uiradiobutton(app.SegmentationModeButtonGroup_Custom);
            app.ToepeakButton_Custom.Text = {'Hip Angle'; ''};
            app.XEditFieldLabel_2 = uilabel(app.CustomTab);
            app.XEditFieldLabel_2.Text = 'X';
            app.Marker_X_Custom = uieditfield(app.CustomTab, 'numeric');
            app.YEditFieldLabel_2 = uilabel(app.CustomTab);
            app.YEditFieldLabel_2.Text = 'Y';
            app.Marker_Y_Custom = uieditfield(app.CustomTab, 'numeric');
            app.ZEditFieldLabel_2 = uilabel(app.CustomTab);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.Marker_Z_Custom = uieditfield(app.CustomTab, 'numeric');
            app.XEditField_2Label_2 = uilabel(app.CustomTab);
            app.XEditField_2Label_2.Text = 'X';
            app.GRF_X_Custom = uieditfield(app.CustomTab, 'numeric');
            app.YEditField_2Label_2 = uilabel(app.CustomTab);
            app.YEditField_2Label_2.Text = 'Y';
            app.GRF_Y_Custom = uieditfield(app.CustomTab, 'numeric');
            app.ZEditField_2Label_2 = uilabel(app.CustomTab);
            app.ZEditField_2Label_2.Text = 'Z';
            app.GRF_Z_Custom = uieditfield(app.CustomTab, 'numeric');
            app.MotionSpeedEditFieldLabel = uilabel(app.CustomTab);
            app.MotionSpeedEditFieldLabel.Text = 'Motion Speed';
            app.MotionSpeedEditField_Custom = uieditfield(app.CustomTab, 'numeric');
            app.MotionTiltEditFieldLabel = uilabel(app.CustomTab);
            app.MotionTiltEditFieldLabel.Text = 'Motion Tilt';
            app.MotionTiltEditField_Custom = uieditfield(app.CustomTab, 'numeric');
            app.MotionDirectionLabel = uilabel(app.CustomTab);
            app.MotionDirectionLabel.Text = 'Motion Direction';
            app.MotionDirectionEditField_Custom = uidropdown(app.CustomTab);
            app.MotionDirectionEditField_Custom.Items = {'X', 'Y', 'Z'};
            app.MotionDirectionEditField_Custom.Value = 'X';
            app.MarkerLeftHanded_Custom = uicheckbox(app.CustomTab);
            app.MarkerLeftHanded_Custom.Text = 'Left-handed';
            app.GRFLeftHanded_Custom = uicheckbox(app.CustomTab);
            app.GRFLeftHanded_Custom.Text = 'Left-handed';
            app.UoETab = uitab(app.TabGroup);
            app.UoETab.Title = 'UoE';
            app.DataTimeDelayEditField_2Label = uilabel(app.UoETab);
            app.DataTimeDelayEditField_2Label.Text = 'Data Time Delay';
            app.DataTimeDelayEditField_UoE = uieditfield(app.UoETab, 'numeric');
            app.DataTimeDelayEditField_UoE.Value = 0.026667;
            app.MarkerRotationsLabel = uilabel(app.UoETab);
            app.MarkerRotationsLabel.Text = 'Marker Rotations';
            app.GRFRotationsLabel = uilabel(app.UoETab);
            app.GRFRotationsLabel.Text = 'GRF Rotations';
            app.XEditFieldLabel = uilabel(app.UoETab);
            app.XEditFieldLabel.Text = 'X';
            app.Marker_X_UoE = uieditfield(app.UoETab, 'numeric');
            app.YEditFieldLabel = uilabel(app.UoETab);
            app.YEditFieldLabel.Text = 'Y';
            app.Marker_Y_UoE = uieditfield(app.UoETab, 'numeric');
            app.Marker_Y_UoE.Value = 90;
            app.ZEditFieldLabel = uilabel(app.UoETab);
            app.ZEditFieldLabel.Text = 'Z';
            app.Marker_Z_UoE = uieditfield(app.UoETab, 'numeric');
            app.XEditField_2Label = uilabel(app.UoETab);
            app.XEditField_2Label.Text = 'X';
            app.GRF_X_UoE = uieditfield(app.UoETab, 'numeric');
            app.YEditField_2Label = uilabel(app.UoETab);
            app.YEditField_2Label.Text = 'Y';
            app.GRF_Y_UoE = uieditfield(app.UoETab, 'numeric');
            app.GRF_Y_UoE.Value = 90;
            app.ZEditField_2Label = uilabel(app.UoETab);
            app.ZEditField_2Label.Text = 'Z';
            app.GRF_Z_UoE = uieditfield(app.UoETab, 'numeric');
            app.SegmentationModeButtonGroup_UoE = uibuttongroup(app.UoETab);
            app.SegmentationModeButtonGroup_UoE.SelectionChangedFcn = createCallbackFcn(app, @disableCutoffUoE, true);
            app.SegmentationModeButtonGroup_UoE.Title = 'Segmentation Mode';
            app.StanceButton_UoE = uiradiobutton(app.SegmentationModeButtonGroup_UoE);
            app.StanceButton_UoE.Text = 'Stance';
            app.StanceButton_UoE.Value = true;
            app.ToepeakButton_UoE = uiradiobutton(app.SegmentationModeButtonGroup_UoE);
            app.ToepeakButton_UoE.Text = 'Hip Angle';
            app.CutoffLabel_2 = uilabel(app.UoETab);
            app.CutoffLabel_2.Text = 'Cutoff';
            app.StanceCutoffEditField_UoE = uieditfield(app.UoETab, 'numeric');
            app.StanceCutoffEditField_UoE.Value = 2;
            app.FeetLabel_3 = uilabel(app.UoETab);
            app.FeetLabel_3.Text = 'Feet';
            app.FeetDropDown_UoE = uidropdown(app.UoETab);
            app.FeetDropDown_UoE.Items = {'Left', 'Right', 'Both'};
            app.FeetDropDown_UoE.Value = 'Left';
            app.MotionSpeedEditField_2Label = uilabel(app.UoETab);
            app.MotionSpeedEditField_2Label.Text = 'Motion Speed';
            app.MotionSpeedEditField_UoE = uieditfield(app.UoETab, 'numeric');
            app.MotionTiltEditField_2Label = uilabel(app.UoETab);
            app.MotionTiltEditField_2Label.Text = 'Motion Tilt';
            app.MotionTiltEditField_UoE = uieditfield(app.UoETab, 'numeric');
            app.MotionDirectionLabel_2 = uilabel(app.UoETab);
            app.MotionDirectionLabel_2.Text = 'Motion Direction';
            app.MotionDirectionEditField_UoE = uidropdown(app.UoETab);
            app.MotionDirectionEditField_UoE.Items = {'X', 'Y', 'Z'};
            app.MotionDirectionEditField_UoE.Value = 'X';
            app.MarkerLeftHanded_UoE = uicheckbox(app.UoETab);
            app.MarkerLeftHanded_UoE.Text = 'Left-handed';
            app.GRFLeftHanded_UoE = uicheckbox(app.UoETab);
            app.GRFLeftHanded_UoE.Text = 'Left-handed';
            app.ProcessButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processData, true);
            app.ProcessButton.Text = 'Process';
            app.SegmentationSwitchLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.SegmentationSwitchLabel.Text = 'Segmentation';
            app.SegmentationSwitch = uiswitch(app.MotionDataProcessingAppUIFigure, 'slider');
            app.SegmentationSwitch.ValueChangedFcn = createCallbackFcn(app, @allowSegmentation, true);
            app.TextArea = uitextarea(app.MotionDataProcessingAppUIFigure);
            app.ComponentofGaitAnalysisToolboxLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.ComponentofGaitAnalysisToolboxLabel.Text = 'Component of Gait Analysis Toolbox  ';
            app.DanielGordon2019Label = uilabel(app.MotionDataProcessingAppUIFigure);
            app.DanielGordon2019Label.Text = 'Daniel Gordon 2019';
            app.DefaultSaveFoldersCheckBox = uicheckbox(app.MotionDataProcessingAppUIFigure);
            app.DefaultSaveFoldersCheckBox.ValueChangedFcn = createCallbackFcn(app, @allowSaveChanges, true);
            app.DefaultSaveFoldersCheckBox.Text = 'Default Save Folders';
            app.DefaultSaveFoldersCheckBox.Value = true;
            app.GRFEditFieldLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.GRFEditFieldLabel.Visible = 'off';
            app.GRFEditFieldLabel.Text = 'GRF';
            app.GRFEditField = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.GRFEditField.Visible = 'off';
            app.GRFEditField.Value = 'GRF';
            app.MarkersEditFieldLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.MarkersEditFieldLabel.Visible = 'off';
            app.MarkersEditFieldLabel.Text = 'Markers';
            app.MarkersEditField = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.MarkersEditField.Visible = 'off';
            app.MarkersEditField.Value = 'Markers';
            app.MotionCompensationSwitchLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.MotionCompensationSwitchLabel.Text = 'Motion Compensation';
            app.MotionCompensationSwitch = uiswitch(app.MotionDataProcessingAppUIFigure, 'slider');
            app.MotionCompensationSwitch.ValueChangedFcn = createCallbackFcn(app, @allowCompensation, true);
            app.SpeedDataFolderButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.SpeedDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getSpeedData, true);
            app.SpeedDataFolderButton.Enable = 'off';
            app.SpeedDataFolderButton.Text = 'Speed Data Folder';
            app.SpeedFolder = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.AutocropCheckBox = uicheckbox(app.MotionDataProcessingAppUIFigure);
            app.AutocropCheckBox.Text = 'Auto-crop';
            app.AutocropCheckBox.Value = true;
            % Show the figure after all components are created
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessMotionData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionDataProcessingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionDataProcessingAppUIFigure)
        end
    end
end