classdef ProcessMotionData2018b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionDataProcessingAppUIFigure  matlab.ui.Figure
        ChooseAnalysisModeDropDownLabel  matlab.ui.control.Label
        ChooseAnalysisModeDropDown      matlab.ui.control.DropDown
        MarkerDataFolderButton          matlab.ui.control.Button
        MarkerFolder                    matlab.ui.control.EditField
        GRFDataFolderButton             matlab.ui.control.Button
        GRFFolder                       matlab.ui.control.EditField
        SaveDirectoryButton             matlab.ui.control.Button
        SaveDirectory                   matlab.ui.control.EditField
        DisplayDetailedInfoCheckBox     matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        CustomTab                       matlab.ui.container.Tab
        MarkerRotationsLabel_2          matlab.ui.control.Label
        GRFRotationsLabel_2             matlab.ui.control.Label
        XEditField_3Label               matlab.ui.control.Label
        Marker_X_Custom                 matlab.ui.control.NumericEditField
        YEditField_3Label               matlab.ui.control.Label
        Marker_Y_Custom                 matlab.ui.control.NumericEditField
        ZEditField_3Label               matlab.ui.control.Label
        Marker_Z_Custom                 matlab.ui.control.NumericEditField
        XEditField_4Label               matlab.ui.control.Label
        GRF_X_Custom                    matlab.ui.control.NumericEditField
        YEditField_4Label               matlab.ui.control.Label
        GRF_Y_Custom                    matlab.ui.control.NumericEditField
        ZEditField_4Label               matlab.ui.control.Label
        GRF_Z_Custom                    matlab.ui.control.NumericEditField
        DataTimeDelayEditField_2Label_2  matlab.ui.control.Label
        DataTimeDelayEditField_Custom   matlab.ui.control.NumericEditField
        StanceCutoffEditField_2Label_2  matlab.ui.control.Label
        StanceCutoffEditField_Custom    matlab.ui.control.NumericEditField
        ModeLabel_4                     matlab.ui.control.Label
        SegmentationModeDropDown_Custom  matlab.ui.control.DropDown
        FeetLabel_2                     matlab.ui.control.Label
        FeetDropDown_Custom             matlab.ui.control.DropDown
        UoETab                          matlab.ui.container.Tab
        DataTimeDelayEditField_2Label   matlab.ui.control.Label
        DataTimeDelayEditField_UoE      matlab.ui.control.NumericEditField
        StanceCutoffEditField_2Label    matlab.ui.control.Label
        StanceCutoffEditField_UoE       matlab.ui.control.NumericEditField
        MarkerRotationsLabel            matlab.ui.control.Label
        GRFRotationsLabel               matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        Marker_X_UoE                    matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        Marker_Y_UoE                    matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        Marker_Z_UoE                    matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        GRF_X_UoE                       matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        GRF_Y_UoE                       matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        GRF_Z_UoE                       matlab.ui.control.NumericEditField
        ModeLabel_3                     matlab.ui.control.Label
        SegmentationModeDropDown_UoE    matlab.ui.control.DropDown
        FeetLabel                       matlab.ui.control.Label
        FeetDropDown_UoE                matlab.ui.control.DropDown
        ProcessButton                   matlab.ui.control.Button
        SegmentationSwitchLabel         matlab.ui.control.Label
        SegmentationSwitch              matlab.ui.control.Switch
        TextArea                        matlab.ui.control.TextArea
        ComponentofGaitAnalysisToolboxLabel  matlab.ui.control.Label
        DanielGordon2019Label           matlab.ui.control.Label
    end


    methods (Access = private)
    
        function setGRFOptions(app, status)
            
            set(app.GRFDataFolderButton, 'Enable', status);
            set(app.GRF_X_UoE, 'Enable', status);
            set(app.GRF_Y_UoE, 'Enable', status);
            set(app.GRF_Z_UoE, 'Enable', status);
            set(app.GRF_X_Custom, 'Enable', status);
            set(app.GRF_Y_Custom, 'Enable', status);
            set(app.GRF_Z_Custom, 'Enable', status);
            
        end
    
        function setSegmentationOptions(app, status)
            
            set(app.SegmentationModeDropDown_UoE, 'Enable', status);
            set(app.SegmentationModeDropDown_Custom, 'Enable', status);
            set(app.StanceCutoffEditField_UoE, 'Enable', status);
            set(app.StanceCutoffEditField_Custom, 'Enable', status);
            set(app.FeetDropDown_UoE, 'Enable', status);
            set(app.FeetDropDown_Custom, 'Enable', status);
            
        end
    
        function setMarkerOptions(app, status)
            
            set(app.MarkerDataFolderButton, 'Enable', status);
            set(app.Marker_X_UoE, 'Enable', status);
            set(app.Marker_Y_UoE, 'Enable', status);
            set(app.Marker_Z_UoE, 'Enable', status);
            set(app.Marker_X_Custom, 'Enable', status);
            set(app.Marker_Y_Custom, 'Enable', status);
            set(app.Marker_Z_Custom, 'Enable', status);
            
        end
    
        function setSynchronisationOptions(app, status)
           
            set(app.DataTimeDelayEditField_Custom, 'Enable', status);
            set(app.DataTimeDelayEditField_UoE, 'Enable', status);
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableOptions(app)
            setSegmentationOptions(app, 'off')
        end

        % Button pushed function: MarkerDataFolderButton
        function getMarkerFolder(app, event)
            app.MarkerFolder.Value = uigetdir([], 'Select folder containing marker data');
        end

        % Value changed function: ChooseAnalysisModeDropDown
        function disableOptions(app, event)
            value = app.ChooseAnalysisModeDropDown.Value;
            switch value
                case 'Static'
                    set(app.GRFDataFolderButton, 'Enable', 'off');
                    setMarkerOptions(app, 'on');
                    set(app.TabGroup, 'Visible', 'off');
                case 'Marker'
                    set(app.TabGroup, 'Visible', 'on');
                    setGRFOptions(app, 'off');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'off');
                case 'GRF'
                    set(app.TabGroup, 'Visible', 'on');
                    setGRFOptions(app, 'on');
                    setMarkerOptions(app, 'off');
                    setSynchronisationOptions(app, 'off');
                case 'Motion'
                    set(app.TabGroup, 'Visible', 'on');
                    setGRFOptions(app, 'on');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'on');
            end
        end

        % Value changed function: SegmentationSwitch
        function allowSegmentation(app, event)
            setSegmentationOptions(app, app.SegmentationSwitch.Value)
        end

        % Button pushed function: GRFDataFolderButton
        function getGRFData(app, event)
            app.GRFFolder.Value = uigetdir([], 'Select folder containing GRF data');
        end

        % Button pushed function: SaveDirectoryButton
        function getOrCreateResultsDir(app, event)
            app.SaveDirectory.Value = uigetdir([], 'Select or create results folder');
        end

        % Button pushed function: ProcessButton
        function processData(app, event)
            if isempty(app.SaveDirectory.Value)
                app.TextArea.Value = 'Processing requires save directory.';
            end
            settings.analysis = app.ChooseAnalysisModeDropDown.Value;
            settings.save_dir = app.SaveDirectory.Value;
            settings.info = app.DisplayDetailedInfoCheckBox.Value;
            type = app.TabGroup.SelectedTab.Title;
            switch app.ChooseAnalysisModeDropDown.Value
                case 'Static'
                    if isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Static processing requires marker data folder.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_rotations{1} = app.(['Marker_X_' type]).Value;
                        settings.marker_rotations{2} = app.(['Marker_Y_' type]).Value;
                        settings.marker_rotations{3} = app.(['Marker_Z_' type]).Value;
                    end
                case 'Marker'
                    if isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Marker processing requires marker data folder.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_rotations{1} = app.(['Marker_X_' type]).Value;
                        settings.marker_rotations{2} = app.(['Marker_Y_' type]).Value;
                        settings.marker_rotations{3} = app.(['Marker_Z_' type]).Value;
                    end
                case 'GRF'
                    if isempty(app.GRFFolder.Value)
                        app.TextArea.Value = 'GRF processing requires GRF data folder.';
                        return
                    else
                        settings.grfs = app.GRFFolder.Value;
                        settings.grf_rotations{1} = app.(['GRF_X_' type]).Value;
                        settings.grf_rotations{2} = app.(['GRF_Y_' type]).Value;
                        settings.grf_rotations{3} = app.(['GRF_Z_' type]).Value;
                    end
                case 'Motion'
                    if isempty(app.GRFFolder.Value) || isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Motion processing requires marker + GRF data folders.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_rotations{1} = app.(['Marker_X_' type]).Value;
                        settings.marker_rotations{2} = app.(['Marker_Y_' type]).Value;
                        settings.marker_rotations{3} = app.(['Marker_Z_' type]).Value;
                        settings.grfs = app.GRFFolder.Value;
                        settings.grf_rotations{1} = app.(['GRF_X_' type]).Value;
                        settings.grf_rotations{2} = app.(['GRF_Y_' type]).Value;
                        settings.grf_rotations{3} = app.(['GRF_Z_' type]).Value;
                        settings.time_delay = app.(['DataTimeDelayEditField_' type]).Value;
                    end
            end
            app.TextArea.Value = 'Processing...';
            drawnow;
            if strcmp(app.SegmentationSwitch.Value, 'On') && ~strcmp(app.ChooseAnalysisModeDropDown.Value, 'Static')
                settings.mode = lower(app.(['SegmentationModeDropDown_' type]).Value);
                settings.cutoff = app.(['StanceCutoffEditField_' type]).Value;
                if strcmp(app.(['FeetDropDown_' type]), 'left')
                    settings.feet = {'left'};
                elseif strcmp(app.(['FeetDropDown_' type]), 'right')
                    settings.feet = {'right'};
                else
                    settings.feet = {'left', 'right'};
                end
            end
            
            err = batchProcessData(settings);
            if ~err
                app.TextArea.Value = 'Processing complete.';
            else
                app.TextArea.Value = 'Processing finished with errors - see MATLAB command window for more details.';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionDataProcessingAppUIFigure = uifigure;
            app.MotionDataProcessingAppUIFigure.Name = 'Motion Data Processing App';
            app.ChooseAnalysisModeDropDownLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.ChooseAnalysisModeDropDownLabel.Text = 'Choose Analysis Mode';
            app.ChooseAnalysisModeDropDown = uidropdown(app.MotionDataProcessingAppUIFigure);
            app.ChooseAnalysisModeDropDown.Items = {'Static', 'Marker', 'GRF', 'Motion'};
            app.ChooseAnalysisModeDropDown.ValueChangedFcn = createCallbackFcn(app, @disableOptions, true);
            app.ChooseAnalysisModeDropDown.Value = 'Static';
            app.MarkerDataFolderButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.MarkerDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getMarkerFolder, true);
            app.MarkerDataFolderButton.Text = 'Marker Data Folder';
            app.MarkerFolder = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.MarkerFolder.Editable = 'off';
            app.GRFDataFolderButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.GRFDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getGRFData, true);
            app.GRFDataFolderButton.Text = 'GRF Data Folder';
            app.GRFFolder = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.GRFFolder.Editable = 'off';
            app.SaveDirectoryButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.SaveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @getOrCreateResultsDir, true);
            app.SaveDirectoryButton.Text = 'Save Directory';
            app.SaveDirectory = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.SaveDirectory.Editable = 'off';
            app.DisplayDetailedInfoCheckBox = uicheckbox(app.MotionDataProcessingAppUIFigure);
            app.DisplayDetailedInfoCheckBox.Text = 'Display Detailed Info';
            app.TabGroup = uitabgroup(app.MotionDataProcessingAppUIFigure);
            app.CustomTab = uitab(app.TabGroup);
            app.CustomTab.Title = 'Custom';
            app.MarkerRotationsLabel_2 = uilabel(app.CustomTab);
            app.MarkerRotationsLabel_2.Text = 'Marker Rotations';
            app.GRFRotationsLabel_2 = uilabel(app.CustomTab);
            app.GRFRotationsLabel_2.Text = 'GRF Rotations';
            app.XEditField_3Label = uilabel(app.CustomTab);
            app.XEditField_3Label.Text = 'X';
            app.Marker_X_Custom = uieditfield(app.CustomTab, 'numeric');
            app.YEditField_3Label = uilabel(app.CustomTab);
            app.YEditField_3Label.Text = 'Y';
            app.Marker_Y_Custom = uieditfield(app.CustomTab, 'numeric');
            app.ZEditField_3Label = uilabel(app.CustomTab);
            app.ZEditField_3Label.Text = 'Z';
            app.Marker_Z_Custom = uieditfield(app.CustomTab, 'numeric');
            app.XEditField_4Label = uilabel(app.CustomTab);
            app.XEditField_4Label.Text = 'X';
            app.GRF_X_Custom = uieditfield(app.CustomTab, 'numeric');
            app.YEditField_4Label = uilabel(app.CustomTab);
            app.YEditField_4Label.Text = 'Y';
            app.GRF_Y_Custom = uieditfield(app.CustomTab, 'numeric');
            app.ZEditField_4Label = uilabel(app.CustomTab);
            app.ZEditField_4Label.Text = 'Z';
            app.GRF_Z_Custom = uieditfield(app.CustomTab, 'numeric');
            app.DataTimeDelayEditField_2Label_2 = uilabel(app.CustomTab);
            app.DataTimeDelayEditField_2Label_2.Text = 'Data Time Delay';
            app.DataTimeDelayEditField_Custom = uieditfield(app.CustomTab, 'numeric');
            app.StanceCutoffEditField_2Label_2 = uilabel(app.CustomTab);
            app.StanceCutoffEditField_2Label_2.Text = 'Stance Cutoff';
            app.StanceCutoffEditField_Custom = uieditfield(app.CustomTab, 'numeric');
            app.StanceCutoffEditField_Custom.Value = 40;
            app.ModeLabel_4 = uilabel(app.CustomTab);
            app.ModeLabel_4.Text = 'Mode';
            app.SegmentationModeDropDown_Custom = uidropdown(app.CustomTab);
            app.SegmentationModeDropDown_Custom.Items = {'Stance', 'Toe-peak'};
            app.SegmentationModeDropDown_Custom.Value = 'Stance';
            app.FeetLabel_2 = uilabel(app.CustomTab);
            app.FeetLabel_2.Text = 'Feet';
            app.FeetDropDown_Custom = uidropdown(app.CustomTab);
            app.FeetDropDown_Custom.Items = {'Left', 'Right', 'Both'};
            app.FeetDropDown_Custom.Value = 'Left';
            app.UoETab = uitab(app.TabGroup);
            app.UoETab.Title = 'UoE';
            app.DataTimeDelayEditField_2Label = uilabel(app.UoETab);
            app.DataTimeDelayEditField_2Label.Text = 'Data Time Delay';
            app.DataTimeDelayEditField_UoE = uieditfield(app.UoETab, 'numeric');
            app.DataTimeDelayEditField_UoE.Value = 0.026667;
            app.StanceCutoffEditField_2Label = uilabel(app.UoETab);
            app.StanceCutoffEditField_2Label.Text = 'Stance Cutoff';
            app.StanceCutoffEditField_UoE = uieditfield(app.UoETab, 'numeric');
            app.StanceCutoffEditField_UoE.Value = 40;
            app.MarkerRotationsLabel = uilabel(app.UoETab);
            app.MarkerRotationsLabel.Text = 'Marker Rotations';
            app.GRFRotationsLabel = uilabel(app.UoETab);
            app.GRFRotationsLabel.Text = 'GRF Rotations';
            app.XEditFieldLabel = uilabel(app.UoETab);
            app.XEditFieldLabel.Text = 'X';
            app.Marker_X_UoE = uieditfield(app.UoETab, 'numeric');
            app.YEditFieldLabel = uilabel(app.UoETab);
            app.YEditFieldLabel.Text = 'Y';
            app.Marker_Y_UoE = uieditfield(app.UoETab, 'numeric');
            app.Marker_Y_UoE.Value = 270;
            app.ZEditFieldLabel = uilabel(app.UoETab);
            app.ZEditFieldLabel.Text = 'Z';
            app.Marker_Z_UoE = uieditfield(app.UoETab, 'numeric');
            app.XEditField_2Label = uilabel(app.UoETab);
            app.XEditField_2Label.Text = 'X';
            app.GRF_X_UoE = uieditfield(app.UoETab, 'numeric');
            app.YEditField_2Label = uilabel(app.UoETab);
            app.YEditField_2Label.Text = 'Y';
            app.GRF_Y_UoE = uieditfield(app.UoETab, 'numeric');
            app.GRF_Y_UoE.Value = 90;
            app.ZEditField_2Label = uilabel(app.UoETab);
            app.ZEditField_2Label.Text = 'Z';
            app.GRF_Z_UoE = uieditfield(app.UoETab, 'numeric');
            app.ModeLabel_3 = uilabel(app.UoETab);
            app.ModeLabel_3.Text = 'Mode';
            app.SegmentationModeDropDown_UoE = uidropdown(app.UoETab);
            app.SegmentationModeDropDown_UoE.Items = {'Stance', 'Toe-peak'};
            app.SegmentationModeDropDown_UoE.Value = 'Stance';
            app.FeetLabel = uilabel(app.UoETab);
            app.FeetLabel.Text = 'Feet';
            app.FeetDropDown_UoE = uidropdown(app.UoETab);
            app.FeetDropDown_UoE.Items = {'Left', 'Right', 'Both'};
            app.FeetDropDown_UoE.Value = 'Left';
            app.ProcessButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processData, true);
            app.ProcessButton.Text = 'Process';
            app.SegmentationSwitchLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.SegmentationSwitchLabel.Text = 'Segmentation';
            app.SegmentationSwitch = uiswitch(app.MotionDataProcessingAppUIFigure, 'slider');
            app.SegmentationSwitch.ValueChangedFcn = createCallbackFcn(app, @allowSegmentation, true);
            app.TextArea = uitextarea(app.MotionDataProcessingAppUIFigure);
            app.ComponentofGaitAnalysisToolboxLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.ComponentofGaitAnalysisToolboxLabel.Text = 'Component of Gait Analysis Toolbox  ';
            app.DanielGordon2019Label = uilabel(app.MotionDataProcessingAppUIFigure);
            app.DanielGordon2019Label.Text = 'Daniel Gordon 2019';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProcessMotionData2018b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionDataProcessingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionDataProcessingAppUIFigure)
        end
    end
end